classdef App1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure matlab.ui.Figure         % UI Figure
        Button   matlab.ui.control.Button % Select file
        UIAxes   matlab.ui.control.UIAxes % Emotion
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button button pushed function
        function ButtonPushed_FileSelect(app)
            [fileName,pathName] = uigetfile({'*.txt', 'TXT (*.txt)'; '*.*', 'All Files (*.*)'}, ...
                                                'Selcet TXT File', 'MultiSelect', 'on');
            [wordLevel_Transcript,wordLevel_Transcript_Timings,arousal_Data,arousal_Timings,valence_Data, valence_Timings,power_Data, power_Timings,expectation_Data,expectation_Timings,intensity_Data,intensity_Timings,fear_Data,fear_Timings,anger_Data,anger_Timings,happiness_Data,happiness_Timings,saddness_Data,saddness_Timings,disgust_Data, disgust_Timings,contempt_Data,contempt_Timings,amusement_Data,amusement_Timings]=Data_Import (fileName, pathName);
            % draw the plot of the emotional parameters
            plot(app.UIAxes,arousal_Timings,'-r');
            hold(app.UIAxes);
            plot(app.UIAxes,[2,3,4,5],'--b');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_FileSelect);
            app.Button.Text = 'Select file';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Emotion');
            xlabel(app.UIAxes, 'Time');
            ylabel(app.UIAxes, 'Emotional Parameter');
        end
    end

    methods (Access = public)

        % Construct app
        function app = App1()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end